classdef DDcentrales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CENTRALES          matlab.ui.Figure
        ResultadoLabel     matlab.ui.control.Label
        PolinomioLabel     matlab.ui.control.Label
        CargarDatosButton  matlab.ui.control.Button
        interpolar         matlab.ui.control.NumericEditField
        IngreseelvalordexainterpolarLabel  matlab.ui.control.Label
        gradoPoli          matlab.ui.control.NumericEditField
        IngreseelgradodelPolinomioEditFieldLabel  matlab.ui.control.Label
        DIFERENCIASDIVIDASCENTRALESLabel  matlab.ui.control.Label
        axes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarDatosButton
        function CargarDatosButtonPushed(app, event)
            arch = 'pruebas.xlsx';
            entrada = xlsread(arch, -1);

            n=app.gradoPoli.Value;

            X = entrada(:,1);
            Y = entrada(:,2);
           
            F(:,1)=Y;
            U=zeros(n-1);
            a=n;
            for j=2:n+1
                for i=1:a
                    F(i,j)=F(i+1,j-1)-F(i,j-1);     
                end
                for k=1:a-1
                    U(k,j-1)=(F(k,j)+F(k+1,j))/2;     
                end
                a=a-1;
            end
            Polinomio=0;
            Productorio=1;
            a=n+1;
            r=3;
            i=1;
            V=zeros(n+1);
            h=X(2)-X(1);
            syms x;
            if (rem(n+1,2)==0)
                P=(x-X((n+1)/2))/h;
            else
                P=(x-X((n+2)/2))/h;
            end
            P2=P*P;
            for j=1:n+1    
                if(rem(j,2)~=0)
                    if(j==1)
                        V(i)=F((a+1)/2,j);
                        i=i+1;
                        Polinomio=Polinomio+F((a+1)/2,j);
                    else
                        V(i)=F((a+1)/2,j);
                        i=i+1;
                        for l=0:j-r
                            Productorio=Productorio*(P2-l^2);
                        end
                        %AUX=symprod(P2-k^2,k,0,j-r)/factorial(j-1);
                        AUX=Productorio/factorial(j-1);
                        Polinomio=Polinomio+AUX*F((a+1)/2,j);
                    end
                    if (j~=n+1)
                        if(j==1)
                            V(i)=U((a-1)/2,j);
                            i=i+1;
                            Polinomio=Polinomio+P*U((a-1)/2,j);
                        else
                            V(i)=U((a-1)/2,j);
                            i=i+1;
                            CB = nchoosek(P+j-r+1,j);
                            Polinomio=Polinomio+CB*U((a-1)/2,j);
                        end
                    end
                    r=r+1;
                end
                a=a-1;
            end
            
            %disp('La matriz de diferencias divididas es:');
            %disp(F);

            Polinomio=expand(Polinomio);
            pretty(Polinomio);

            polinom = "$$P(x) = " + latex(Polinomio) + "$$";
            
            app.PolinomioLabel.Text = polinom;
            
            x=app.interpolar.Value;

            vi=eval(Polinomio);
            
            app.ResultadoLabel.Visible = "on";
    
            resultado = "El resultado de la interpolación es: " + num2str(vi);
            
            app.ResultadoLabel.Text = resultado;

            fplot(app.axes, Polinomio,'k-','LineWidth',2);%Grafica la función de color negro y grosor 2
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CENTRALES = uifigure('Visible', 'off');
            app.CENTRALES.Name = 'MATLAB App';
            app.axes = uiaxes(app.CENTRALES);
            title(app.axes, 'Gráfico')
            app.axes.XLim = [-100000 100000];
            app.axes.YLim = [-100000 100000];
            app.axes.ZLim = [-100000 100000];
            app.DIFERENCIASDIVIDASCENTRALESLabel = uilabel(app.CENTRALES);
            app.DIFERENCIASDIVIDASCENTRALESLabel.Text = 'DIFERENCIAS DIVIDAS CENTRALES';
            app.IngreseelgradodelPolinomioEditFieldLabel = uilabel(app.CENTRALES);
            app.IngreseelgradodelPolinomioEditFieldLabel.Text = 'Ingrese el grado del Polinomio: ';
            app.gradoPoli = uieditfield(app.CENTRALES, 'numeric');
            app.gradoPoli.Limits = [0 10];
            app.IngreseelvalordexainterpolarLabel = uilabel(app.CENTRALES);
            app.IngreseelvalordexainterpolarLabel.Text = 'Ingrese el valor de "x" a interpolar: ';
            app.interpolar = uieditfield(app.CENTRALES, 'numeric');
            app.CargarDatosButton = uibutton(app.CENTRALES, 'push');
            app.CargarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargarDatosButtonPushed, true);
            app.CargarDatosButton.Text = 'Cargar Datos';
            app.PolinomioLabel = uilabel(app.CENTRALES);
            app.PolinomioLabel.Interpreter = 'latex';
            app.PolinomioLabel.Text = 'Aqui se mostrará el polinomio';
            app.ResultadoLabel = uilabel(app.CENTRALES);
            app.ResultadoLabel.Visible = 'off';
            app.ResultadoLabel.Text = 'Resultado';
            % Show the figure after all components are created
            app.CENTRALES.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DDcentrales

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CENTRALES)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CENTRALES)
        end
    end
end