classdef DDnewton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        newton             matlab.ui.Figure
        gradoPoli          matlab.ui.control.NumericEditField
        IngreseelgradodelpolinomioLabel  matlab.ui.control.Label
        interpolarX        matlab.ui.control.NumericEditField
        IngreseelvalordexainterpolarEditFieldLabel  matlab.ui.control.Label
        polinomio          matlab.ui.control.Label
        resultado          matlab.ui.control.Label
        CargarDatosButton  matlab.ui.control.Button
        NEWTONLabel        matlab.ui.control.Label
        axes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarDatosButton
        function CargarDatosButtonPushed(app, event)
            arch = 'pruebas.xlsx';
            entrada = xlsread(arch, -1);
            n = app.gradoPoli.Value;
            X = entrada(:,1);
            Y = entrada(:,2);
            F(:,1)=Y;
            for j=2:n+1
                for i=j:n+1
                    F(i,j)=[F(i,j-1)-F(i-1,j-1)]/[X(i)-X(i-j+1)];
                end
            end
            disp('El polinomio de newton es');
            syms x;
            fx=F(1,1);
            P=1;
            for i=1:n
                P=P*(x-X(i));
                fx=fx+P*F(i+1,i+1);
            end
            fx=expand(fx);
            pretty(fx);
            
            fplot(app.axes,fx,'k-','LineWidth',2);%Grafica la función de color negro y grosor 2

            polinomio = "$$F(x)= " + latex(fx) + "$$";
            app.polinomio.Text = polinomio;

            x = app.interpolarX.Value;
            vi = eval(fx);
            
            app.resultado.Visible = "on";

            resultado = "El valor interpolado en F(x) es: " + num2str(vi);
            app.resultado.Text = resultado;
            
            
        end

        % Callback function
        function MostrarresultadoButtonPushed(app, event)
            
        end

        % Close request function: newton
        function newtonCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.newton = uifigure('Visible', 'off');
            app.newton.Name = 'MATLAB App';
            app.newton.CloseRequestFcn = createCallbackFcn(app, @newtonCloseRequest, true);
            app.axes = uiaxes(app.newton);
            title(app.axes, 'Gráfico')
            xlabel(app.axes, 'X')
            ylabel(app.axes, 'Y')
            zlabel(app.axes, 'Z')
            app.axes.XLim = [-10000 10000];
            app.axes.YLim = [-10000 10000];
            app.axes.ZLim = [-10000 10000];
            app.NEWTONLabel = uilabel(app.newton);
            app.NEWTONLabel.Text = 'NEWTON';
            app.CargarDatosButton = uibutton(app.newton, 'push');
            app.CargarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargarDatosButtonPushed, true);
            app.CargarDatosButton.Text = 'Cargar Datos';
            app.resultado = uilabel(app.newton);
            app.resultado.Visible = 'off';
            app.resultado.Text = 'resultado';
            app.polinomio = uilabel(app.newton);
            app.polinomio.Interpreter = 'latex';
            app.polinomio.Text = 'Aqui se mostrará el polinomio';
            app.IngreseelvalordexainterpolarEditFieldLabel = uilabel(app.newton);
            app.IngreseelvalordexainterpolarEditFieldLabel.Text = 'Ingrese el valor de "x" a interpolar:';
            app.interpolarX = uieditfield(app.newton, 'numeric');
            app.IngreseelgradodelpolinomioLabel = uilabel(app.newton);
            app.IngreseelgradodelpolinomioLabel.Text = 'Ingrese el grado del polinomio:';
            app.gradoPoli = uieditfield(app.newton, 'numeric');
            % Show the figure after all components are created
            app.newton.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DDnewton

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.newton)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.newton)
        end
    end
end