classdef DDprogresivas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PROGRESIVAS        matlab.ui.Figure
        interpolar         matlab.ui.control.NumericEditField
        IngreseelvalordexainterpolarEditFieldLabel  matlab.ui.control.Label
        gradoPolinom       matlab.ui.control.NumericEditField
        IngreseelgradodelPolinomioEditFieldLabel  matlab.ui.control.Label
        ResultadoLabel     matlab.ui.control.Label
        PolinomioLabel     matlab.ui.control.Label
        CargarDatosButton  matlab.ui.control.Button
        DIFERENCIASDIVIDASPROGRESIVASLabel  matlab.ui.control.Label
        axes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarDatosButton
        function CargarDatosButtonPushed(app, event)
            arch = 'pruebas.xlsx';
            entrada = xlsread(arch, -1);

            n = app.gradoPolinom.Value;

            X = entrada(:,1);
            Y = entrada(:,2);
            
            a=n;
            F(:,1)=Y;
            for j=2:n+1
                for i=1:a
                    F(i,j)=F(i+1,j-1)-F(i,j-1);     
                end
                a=a-1;
            end
            
            h=X(2)-X(1);
            syms x;
            P=(x-X(1))/h;
            Polinomio=Y(1);
            for k=1:n
                CB = nchoosek(P,k);
                Polinomio=Polinomio+(CB*F(1,k+1));
            end
            
            %disp('La matriz de diferencias divididas es:');
            %disp(F);
            
            Polinomio=expand(Polinomio);
            %pretty(Polinomio);

            polinom = "$$P(x) " + latex(Polinomio) + "$$";

            app.PolinomioLabel.Text = polinom;
            
            x = app.interpolar.Value;

            vi=eval(Polinomio);

            app.ResultadoLabel.Visible = "on";
            
            resultado = "El resultado de la interpolación es: " + num2str(vi);
            
            app.ResultadoLabel.Text = resultado;
            
            fplot(app.axes, Polinomio,'k-','LineWidth',2);%Grafica la función de color negro y grosor 2
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PROGRESIVAS = uifigure('Visible', 'off');
            app.PROGRESIVAS.Name = 'MATLAB App';
            app.axes = uiaxes(app.PROGRESIVAS);
            title(app.axes, 'Gráfico')
            app.axes.XLim = [-6000 6000];
            app.axes.YLim = [-6000 6000];
            app.axes.ZLim = [-6000 6000];
            app.DIFERENCIASDIVIDASPROGRESIVASLabel = uilabel(app.PROGRESIVAS);
            app.DIFERENCIASDIVIDASPROGRESIVASLabel.Text = 'DIFERENCIAS DIVIDAS PROGRESIVAS';
            app.CargarDatosButton = uibutton(app.PROGRESIVAS, 'push');
            app.CargarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargarDatosButtonPushed, true);
            app.CargarDatosButton.Text = 'Cargar Datos';
            app.PolinomioLabel = uilabel(app.PROGRESIVAS);
            app.PolinomioLabel.Interpreter = 'latex';
            app.PolinomioLabel.Text = 'Aqui se mostrará el polinomio';
            app.ResultadoLabel = uilabel(app.PROGRESIVAS);
            app.ResultadoLabel.Visible = 'off';
            app.ResultadoLabel.Text = 'Resultado';
            app.IngreseelgradodelPolinomioEditFieldLabel = uilabel(app.PROGRESIVAS);
            app.IngreseelgradodelPolinomioEditFieldLabel.Text = 'Ingrese el grado del Polinomio: ';
            app.gradoPolinom = uieditfield(app.PROGRESIVAS, 'numeric');
            app.IngreseelvalordexainterpolarEditFieldLabel = uilabel(app.PROGRESIVAS);
            app.IngreseelvalordexainterpolarEditFieldLabel.Text = 'Ingrese el valor de "x" a interpolar: ';
            app.interpolar = uieditfield(app.PROGRESIVAS, 'numeric');
            % Show the figure after all components are created
            app.PROGRESIVAS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DDprogresivas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PROGRESIVAS)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PROGRESIVAS)
        end
    end
end