classdef DDregresivas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        REGRESIVAS         matlab.ui.Figure
        interpolar         matlab.ui.control.NumericEditField
        IngreseelvalordexainterpolarEditFieldLabel  matlab.ui.control.Label
        gradoPolinom       matlab.ui.control.NumericEditField
        IngreseelgradodelPolinomioEditFieldLabel  matlab.ui.control.Label
        ResultadoLabel     matlab.ui.control.Label
        PolinomioLabel     matlab.ui.control.Label
        CargarDatosButton  matlab.ui.control.Button
        DIFERENCIASDIVIDASREGRESIVASLabel  matlab.ui.control.Label
        axes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarDatosButton
        function CargarDatosButtonPushed(app, event)
            arch = 'pruebas.xlsx';
            entrada = xlsread(arch, -1);

            n = app.gradoPolinom.Value;

            X = entrada(:,1);
            Y = entrada(:,2);
            
            F(:,1)=Y;
            for j=2:n+1
                for i=j:n+1
                    F(i,j)=F(i,j-1)-F(i-1,j-1);
                end
            end
            
            h=X(2)-X(1);
            syms x;
            P=(x-X(n+1))/h;
            Polinomio=Y(n+1);
            for k=1:n
                CB = nchoosek(P+k-1,k);
                Polinomio=Polinomio+(CB*F(n+1,k+1));
            end
            
            %disp('La matriz de diferencias divididas es:');
            %disp(F);
            
            Polinomio=expand(Polinomio);
            pretty(Polinomio);

            polinom = "$$P(x) " + latex(Polinomio) + "$$";

            app.PolinomioLabel.Text = polinom;
            
            x = app.interpolar.Value;   
            vi=eval(Polinomio);

            app.ResultadoLabel.Visible = "on";
            
            resultado = "El resultado de la interpolación es: " + num2str(vi);
            
            app.ResultadoLabel.Text = resultado;
            
            fplot(app.axes,Polinomio,'k-','LineWidth',2);%Grafica la función de color negro y grosor 2
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.REGRESIVAS = uifigure('Visible', 'off');
            app.REGRESIVAS.Name = 'MATLAB App';
            app.axes = uiaxes(app.REGRESIVAS);
            title(app.axes, 'Gráfico')
            app.axes.XLim = [-6000 6000];
            app.axes.YLim = [-6000 6000];
            app.axes.ZLim = [-6000 6000];
            app.DIFERENCIASDIVIDASREGRESIVASLabel = uilabel(app.REGRESIVAS);
            app.DIFERENCIASDIVIDASREGRESIVASLabel.Text = 'DIFERENCIAS DIVIDAS REGRESIVAS';
            app.CargarDatosButton = uibutton(app.REGRESIVAS, 'push');
            app.CargarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargarDatosButtonPushed, true);
            app.CargarDatosButton.Text = 'Cargar Datos';
            app.PolinomioLabel = uilabel(app.REGRESIVAS);
            app.PolinomioLabel.Interpreter = 'latex';
            app.PolinomioLabel.Text = 'Aqui se mostrará el polinomio';
            app.ResultadoLabel = uilabel(app.REGRESIVAS);
            app.ResultadoLabel.Visible = 'off';
            app.ResultadoLabel.Text = 'Resultado';
            app.IngreseelgradodelPolinomioEditFieldLabel = uilabel(app.REGRESIVAS);
            app.IngreseelgradodelPolinomioEditFieldLabel.Text = 'Ingrese el grado del Polinomio: ';
            app.gradoPolinom = uieditfield(app.REGRESIVAS, 'numeric');
            app.IngreseelvalordexainterpolarEditFieldLabel = uilabel(app.REGRESIVAS);
            app.IngreseelvalordexainterpolarEditFieldLabel.Text = 'Ingrese el valor de "x" a interpolar: ';
            app.interpolar = uieditfield(app.REGRESIVAS, 'numeric');
            % Show the figure after all components are created
            app.REGRESIVAS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DDregresivas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.REGRESIVAS)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.REGRESIVAS)
        end
    end
end