classdef calculoErrores < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PolinomioLabel2        matlab.ui.control.Label
        interpolar             matlab.ui.control.NumericEditField
        IngreseelvalordexainterpolarEditFieldLabel  matlab.ui.control.Label
        nroPuntos              matlab.ui.control.NumericEditField
        IngreseelgradodelPolinomioEditFieldLabel  matlab.ui.control.Label
        ResultadoLabel         matlab.ui.control.Label
        PolinomioLabel         matlab.ui.control.Label
        CargarDatosButton      matlab.ui.control.Button
        CALCULODEERRORESLabel  matlab.ui.control.Label
        axes2                  matlab.ui.control.UIAxes
        axes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarDatosButton
        function CargarDatosButtonPushed(app, event)
            arch = 'pruebas.xlsx';
            entrada = xlsread(arch, -1);

            n= app.nroPuntos.Value;

            X = entrada(:,1);

            Y = entrada(:,2);

            syms x;
            p=0;
            
            for i=1:n+1
                L=1;
                for j=1:n+1
                    if(i~=j)
                        L=L*(x-X(j))/(X(i)-X(j));
                    end
                end
                p=p+L*Y(i);
               
            end
            p=expand(p);
            %pretty(p);

            polinom = "$$P(x) =" + latex(p) + "$$";

            app.PolinomioLabel.Text = polinom;
            
            %DD Progresivas
            a=n;
            F(:,1)=Y;
            for j=2:n+1
                for i=1:a
                    F(i,j)=F(i+1,j-1)-F(i,j-1);     
                end
                a=a-1;
            end
            
            h=X(2)-X(1);
            syms x;
            P=(x-X(1))/h;
            Polinomio=Y(1);
            for k=1:n
                CB = nchoosek(P,k);
                Polinomio=Polinomio+(CB*F(1,k+1));
            end
            
            Polinomio=expand(Polinomio);
            pretty(Polinomio);
            
            polinom2 = "$$P(x) =" + latex(Polinomio) + "$$";

            app.PolinomioLabel2.Text = polinom2;

            fplot(app.axes2,Polinomio,'k-','LineWidth',2);%Grafica la función de color negro y grosor 2
            fplot(app.axes,p,'k-','LineWidth',2);%Grafica la función de color negro y grosor 2


            x=app.interpolar.Value;
            vi=eval(p);
            vu=eval(Polinomio);
            error=vi-vu;

            app.ResultadoLabel.Visible = "on";
            
            resultado = "El error resultante es: " + num2str(error);
            
            app.ResultadoLabel.Text = resultado;
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes = uiaxes(app.UIFigure);
            title(app.axes, 'Lagrange')
            app.axes.XLim = [-6000 6000];
            app.axes.YLim = [-6000 6000];
            app.axes.ZLim = [-6000 6000];
            app.axes2 = uiaxes(app.UIFigure);
            title(app.axes2, 'Diferencias Divididas')
            app.axes2.XLim = [-6000 6000];
            app.axes2.YLim = [-6000 6000];
            app.axes2.ZLim = [-6000 6000];
            app.CALCULODEERRORESLabel = uilabel(app.UIFigure);
            app.CALCULODEERRORESLabel.Text = 'CALCULO DE ERRORES';
            app.CargarDatosButton = uibutton(app.UIFigure, 'push');
            app.CargarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargarDatosButtonPushed, true);
            app.CargarDatosButton.Text = 'Cargar Datos';
            app.PolinomioLabel = uilabel(app.UIFigure);
            app.PolinomioLabel.Interpreter = 'latex';
            app.PolinomioLabel.Text = 'Aqui se mostrará el polinomio de Lagrange';
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Visible = 'off';
            app.ResultadoLabel.Text = 'Resultado';
            app.IngreseelgradodelPolinomioEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelgradodelPolinomioEditFieldLabel.Text = 'Ingrese el grado del Polinomio: ';
            app.nroPuntos = uieditfield(app.UIFigure, 'numeric');
            app.IngreseelvalordexainterpolarEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelvalordexainterpolarEditFieldLabel.Text = 'Ingrese el valor de "x" a interpolar: ';
            app.interpolar = uieditfield(app.UIFigure, 'numeric');
            app.PolinomioLabel2 = uilabel(app.UIFigure);
            app.PolinomioLabel2.Interpreter = 'latex';
            app.PolinomioLabel2.Text = 'Aqui se mostrará el polinomio de Diferencias Divididas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calculoErrores

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end