classdef lagrange < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        interpolar                 matlab.ui.control.NumericEditField
        IngreseelvalordexainterpolarEditFieldLabel  matlab.ui.control.Label
        nroPuntos                  matlab.ui.control.NumericEditField
        IngreseelgradodelPolinomioEditFieldLabel  matlab.ui.control.Label
        ResultadoLabel             matlab.ui.control.Label
        PolinomioLabel             matlab.ui.control.Label
        CargarDatosButton          matlab.ui.control.Button
        POLINOMIODELANGRANGELabel  matlab.ui.control.Label
        axes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarDatosButton
        function CargarDatosButtonPushed(app, event)
            arch = 'pruebas.xlsx';
            entrada = xlsread(arch, -1);

            n= app.nroPuntos.Value;

            X = entrada(:,1);
            Y = entrada(:,2);

            syms x;
            p=0;
            
            for i=1:n
                L=1;
                for j=1:n
                    if(i~=j)
                        L=L*(x-X(j))/(X(i)-X(j));
                    end
                end
                p=p+L*Y(i)
               
            end
            p=expand(p);
            %pretty(p);

            polinom = "$$P(x) " + latex(p) + "$$";

            app.PolinomioLabel.Text = polinom;
            
            x = app.interpolar.Value;
            vi=eval(p);
            
            app.ResultadoLabel.Visible = "on";
            
            resultado = "El resultado de la interpolación es: " + num2str(vi);
            
            app.ResultadoLabel.Text = resultado;

            fplot(app.axes,p,'k-','LineWidth',2);%Grafica la función de color negro y grosor 2
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes = uiaxes(app.UIFigure);
            title(app.axes, 'Gráfico')
            app.axes.XLim = [-6000 6000];
            app.axes.YLim = [-6000 6000];
            app.axes.ZLim = [-6000 6000];
            app.POLINOMIODELANGRANGELabel = uilabel(app.UIFigure);
            app.POLINOMIODELANGRANGELabel.Text = 'POLINOMIO DE LANGRANGE';
            app.CargarDatosButton = uibutton(app.UIFigure, 'push');
            app.CargarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargarDatosButtonPushed, true);
            app.CargarDatosButton.Text = 'Cargar Datos';
            app.PolinomioLabel = uilabel(app.UIFigure);
            app.PolinomioLabel.Interpreter = 'latex';
            app.PolinomioLabel.Text = 'Aqui se mostrará el polinomio';
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Visible = 'off';
            app.ResultadoLabel.Text = 'Resultado';
            app.IngreseelgradodelPolinomioEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelgradodelPolinomioEditFieldLabel.Text = 'Ingrese el grado del Polinomio: ';
            app.nroPuntos = uieditfield(app.UIFigure, 'numeric');
            app.IngreseelvalordexainterpolarEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelvalordexainterpolarEditFieldLabel.Text = 'Ingrese el valor de "x" a interpolar: ';
            app.interpolar = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lagrange

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end