classdef neville < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        interpolar           matlab.ui.control.NumericEditField
        IngreseelvalordexainterpolarEditFieldLabel  matlab.ui.control.Label
        nroPuntos            matlab.ui.control.NumericEditField
        IngreseelgradodelPolinomioEditFieldLabel  matlab.ui.control.Label
        ResultadoLabel       matlab.ui.control.Label
        PolinomioLabel       matlab.ui.control.Label
        CargarDatosButton    matlab.ui.control.Button
        MTODODENEVILLELabel  matlab.ui.control.Label
        axes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarDatosButton
        function CargarDatosButtonPushed(app, event)
            arch = 'pruebas.xlsx';
            entrada = xlsread(arch, -1);

            n= app.nroPuntos.Value;

            X = entrada(:,1);
            Y = entrada(:,2);
            
            for i=1:n+1
                Q(i,1)=Y(i);
            end
            
            %algoritmo
            for j=2:n+1
                for i=j:n+1
                    Q(i,j)=(((X(i)-X(i-j+1))*Q(i,j-1))-((X(j)-X(i))*Q(i-1,j-1)))/(X(i)-X(i-j+1));
                end
            end
            %disp('La matriz:');
            %disp(Q);
            
            syms x;
            fx=Q(1,1);
            P=1;
            for i=1:n
                P=P*(x-X(i));
                fx=fx+P*Q(i+1,i+1);
            end
            fx=expand(fx);
            %pretty(fx);
            
            polinom = "$$P(x) = " + latex(fx) + "$$";

            app.PolinomioLabel.Text = polinom;

            x = app.interpolar.Value;
            vi=eval(fx);
            
            app.ResultadoLabel.Visible = "on";
            
            resultado = "El resultado de la interpolación es: " + num2str(vi);
            
            app.ResultadoLabel.Text = resultado;
            
            fplot(app.axes,fx,'k-','LineWidth',2);%Grafica la función de color negro y grosor 2
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes = uiaxes(app.UIFigure);
            title(app.axes, 'Gráfico')
            app.axes.XLim = [-6000 6000];
            app.axes.YLim = [-6000 6000];
            app.axes.ZLim = [-6000 6000];
            app.MTODODENEVILLELabel = uilabel(app.UIFigure);
            app.MTODODENEVILLELabel.Text = 'MÉTODO DE NEVILLE';
            app.CargarDatosButton = uibutton(app.UIFigure, 'push');
            app.CargarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargarDatosButtonPushed, true);
            app.CargarDatosButton.Text = 'Cargar Datos';
            app.PolinomioLabel = uilabel(app.UIFigure);
            app.PolinomioLabel.Interpreter = 'latex';
            app.PolinomioLabel.Text = 'Aqui se mostrará el polinomio';
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Visible = 'off';
            app.ResultadoLabel.Text = 'Resultado';
            app.IngreseelgradodelPolinomioEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelgradodelPolinomioEditFieldLabel.Text = 'Ingrese el grado del Polinomio: ';
            app.nroPuntos = uieditfield(app.UIFigure, 'numeric');
            app.IngreseelvalordexainterpolarEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelvalordexainterpolarEditFieldLabel.Text = 'Ingrese el valor de "x" a interpolar: ';
            app.interpolar = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = neville

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end