classdef poliTaylor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        interpolar                     matlab.ui.control.NumericEditField
        IngreseelvalordexainterpolarLabel  matlab.ui.control.Label
        funcion                        matlab.ui.control.EditField
        IngreselafuncinEditFieldLabel  matlab.ui.control.Label
        valueA                         matlab.ui.control.NumericEditField
        IngreseelValordeaLabel         matlab.ui.control.Label
        nroPuntos                      matlab.ui.control.NumericEditField
        IngreseelgradodelPolinomioEditFieldLabel  matlab.ui.control.Label
        ResultadoLabel                 matlab.ui.control.Label
        PolinomioLabel                 matlab.ui.control.Label
        MostrarResultadoButton         matlab.ui.control.Button
        POLINOMIODETAYLORLabel         matlab.ui.control.Label
        axes                           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MostrarResultadoButton
        function MostrarResultadoButtonPushed(app, event)
            %Valores de Entrada:
            syms x;
            a = app.valueA.Value;
            N0 = app.nroPuntos.Value;
            f = str2sym(app.funcion.Value);
           
            T=subs(f,a);
            for k=1:N0
                %Se repetira N0 veces hasta llegar al orden del polinomio
                df=diff(f);
                %calculamos la derivada de la funcion
                T=T+subs(df,a)*((x-a)^k)/factorial(k);
                %sera donde se guarde nuestro polinomio
                f=df;
                %hacemos que la derivada de f sea f para asi
                %en la iteracion yr derivando ciclicamente y
                %la primera, segunda....,nesima-derivada.
            end
            %pretty(T);
            
            polinom = "$$P(x) = " + latex(T) + "$$";

            app.PolinomioLabel.Text = polinom;

            x = app.interpolar.Value;
            vi=eval(T);
            
            app.ResultadoLabel.Visible = "on";
            
            resultado = "El resultado de la interpolación es: " + num2str(vi);
            
            app.ResultadoLabel.Text = resultado;
            
            
            fplot(app.axes,T,'k-','LineWidth',2);%Grafica la función de color negro y grosor 2
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes = uiaxes(app.UIFigure);
            title(app.axes, 'Polinomio')
            app.axes.XLim = [-10 10];
            app.axes.YLim = [-10 10];
            app.axes.ZLim = [-10 10];
            app.POLINOMIODETAYLORLabel = uilabel(app.UIFigure);
            app.POLINOMIODETAYLORLabel.Text = 'POLINOMIO DE TAYLOR';
            app.MostrarResultadoButton = uibutton(app.UIFigure, 'push');
            app.MostrarResultadoButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarResultadoButtonPushed, true);
            app.MostrarResultadoButton.Text = 'Mostrar Resultado';
            app.PolinomioLabel = uilabel(app.UIFigure);
            app.PolinomioLabel.Interpreter = 'latex';
            app.PolinomioLabel.Text = 'Aqui se mostrará el polinomio';
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Visible = 'off';
            app.ResultadoLabel.Text = 'Resultado';
            app.IngreseelgradodelPolinomioEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelgradodelPolinomioEditFieldLabel.Text = 'Ingrese el grado del Polinomio: ';
            app.nroPuntos = uieditfield(app.UIFigure, 'numeric');
            app.nroPuntos.Value = 4;
            app.IngreseelValordeaLabel = uilabel(app.UIFigure);
            app.IngreseelValordeaLabel.Text = 'Ingrese el Valor de a:';
            app.valueA = uieditfield(app.UIFigure, 'numeric');
            app.valueA.Value = 1;
            app.IngreselafuncinEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafuncinEditFieldLabel.Text = 'Ingrese la función:';
            app.funcion = uieditfield(app.UIFigure, 'text');
            app.funcion.Placeholder = 'Ejemplo:    sin(x)';
            app.funcion.Value = 'sin(x)';
            app.IngreseelvalordexainterpolarLabel = uilabel(app.UIFigure);
            app.IngreseelvalordexainterpolarLabel.Text = 'Ingrese el valor de ''x'' a interpolar: ';
            app.interpolar = uieditfield(app.UIFigure, 'numeric');
            app.interpolar.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = poliTaylor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end